classdef GUI_AVDC < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MainScreen                      matlab.ui.Figure
        AutonomousDrivingCycleGeneratorbasedonDrvingStyleLabel  matlab.ui.control.Label
        MainMenuPanel                   matlab.ui.container.Panel
        DrivingStylePanel               matlab.ui.container.Panel
        SafetySpinnerLabel              matlab.ui.control.Label
        SafetySpinner                   matlab.ui.control.Spinner
        ComfortSpinnerLabel             matlab.ui.control.Label
        ComfortSpinner                  matlab.ui.control.Spinner
        SwiftnessSpinnerLabel           matlab.ui.control.Label
        SwiftnessSpinner                matlab.ui.control.Spinner
        DSstatusLabel                   matlab.ui.control.Label
        StatusLamp                      matlab.ui.control.Lamp
        ControllerParametersPanel       matlab.ui.container.Panel
        t_setEditField                  matlab.ui.control.NumericEditField
        t_setEditFieldLabel             matlab.ui.control.Label
        C_brkEditFieldLabel             matlab.ui.control.Label
        C_brkEditField                  matlab.ui.control.NumericEditField
        P_aEditFieldLabel               matlab.ui.control.Label
        P_aEditField                    matlab.ui.control.NumericEditField
        P_vEditFieldLabel               matlab.ui.control.Label
        P_vEditField                    matlab.ui.control.NumericEditField
        C_vsetEditFieldLabel            matlab.ui.control.Label
        C_vsetEditField                 matlab.ui.control.NumericEditField
        a_maxEditFieldLabel             matlab.ui.control.Label
        a_maxEditField                  matlab.ui.control.NumericEditField
        j_maxEditFieldLabel             matlab.ui.control.Label
        j_maxEditField                  matlab.ui.control.NumericEditField
        ovt_tolEditFieldLabel           matlab.ui.control.Label
        ovt_tolEditField                matlab.ui.control.NumericEditField
        ActionsPanel                    matlab.ui.container.Panel
        GenerateCycleButton             matlab.ui.control.Button
        ExportCycleButton               matlab.ui.control.Button
        ExitButton                      matlab.ui.control.Button
        ExportallDataButton             matlab.ui.control.Button
        InputModeButtonGroup            matlab.ui.container.ButtonGroup
        DrivingStyleButton              matlab.ui.control.RadioButton
        ControllerParameterButton       matlab.ui.control.RadioButton
        TrafficSettingsPanel            matlab.ui.container.Panel
        TrafficCycleDropDownLabel       matlab.ui.control.Label
        TrafficCycleDropDown            matlab.ui.control.DropDown
        TrafficIntervalsEditFieldLabel  matlab.ui.control.Label
        TrafficIntervalsEditField       matlab.ui.control.NumericEditField
        MessagesTextAreaLabel           matlab.ui.control.Label
        MessagesTextArea                matlab.ui.control.TextArea
        GeneratedDrivingCyclePanel      matlab.ui.container.Panel
        SelectionImage                  matlab.ui.control.Image
        UIAxes                          matlab.ui.control.UIAxes
        UIAxes_2                        matlab.ui.control.UIAxes
        UIAxes_3                        matlab.ui.control.UIAxes
        UIAxes_4                        matlab.ui.control.UIAxes
        UIAxes_5                        matlab.ui.control.UIAxes
        UIAxes_6                        matlab.ui.control.UIAxes
        TUMlogo                         matlab.ui.control.Image
    end

    %% General Information:
    
    % Designed by Xucheng Duan, Ferdinand Schockenhoff (FTM, Techical University of Munich)
    % Template David Fscher, Ferdinand Schockenhoff (FTM, Techical University of Munich)
    %---------------------------------------
    % Created on: 2022.01.22
    % last update: 2022.04.25
    %---------------------------------------
    % Version: Matlab2020b
    %---------------------------------------
    % Description: Autonomous Drving Cycle Generator based on Driving Style
    %---------------------------------------
    % Input: Driving Style (Comfort, Safety, Swiftness)
    %     or, direct input of controller parameters
    % 
    %---------------------------------------
    % Output: Driving Cycle
    %


    properties (Access = private)
        oldpath = addpath('UI_Elements\'); % Needed for first build up of app (TUM image)
        PlotSelect = 1; % current selected plot
        CaliTab = table(); % calibration table for parameters
        DC_Logs = cell(6); % variables for logs of 6 generated driving cycles
        DC_Para = cell(6); % variables for parameters of 6 generated driving cycles
    end
    
    methods (Access = private)
        
        function results = updateDS(app) % update driving cycle
            % load settings
            Comf = app.ComfortSpinner.Value;
            Safe = app.SafetySpinner.Value;
            Fast = app.SwiftnessSpinner.Value;
            % find corresponding parameters
            pos_para = 0;
            for ii = 1:size(app.CaliTab,1)
                if app.CaliTab.Var_Comf(ii) == Comf
                    if app.CaliTab.Var_Safe(ii) == Safe
                        if app.CaliTab.Var_Fast(ii) == Fast
                            pos_para = ii;
                            break;
                        end
                    end
                end
            end
            if pos_para == 0
            % input DS not found
                app.GenerateCycleButton.Enable = "off";
                % find nearest point
                distance = ones(size(app.CaliTab,1),1) * 100;
                for ii = 1:size(app.CaliTab,1)
                    distance(ii) = norm([app.CaliTab.Var_Comf(ii) - Comf,...
                        app.CaliTab.Var_Safe(ii) - Safe,...
                        app.CaliTab.Var_Fast(ii) - Fast,]);
                end
                [~,nearest] = mink(distance,3);
                % update status
                app.StatusLamp.Color = [196  7  27] ./255; % red
                status_str = "Driving style input is not available!";
                status_str2 = "Nearest Points:";
                for ii = 1:length(nearest)
                    status_str2 = join([status_str2," (",num2str(app.CaliTab.Var_Comf(nearest(ii))),",",...
                        num2str(app.CaliTab.Var_Safe(nearest(ii))),",",num2str(app.CaliTab.Var_Fast(nearest(ii))),")"],'');
                end
                app.DSstatusLabel.Text = [status_str,status_str2];
                app.GenerateCycleButton.Enable = "off";
            else
            % input DS is found
                app.GenerateCycleButton.Enable = "on";
                % update parameters
                Paraset = app.CaliTab{pos_para,(1:8)};
                app.t_setEditField.Value = Paraset(1);
                app.P_aEditField.Value = Paraset(2);
                app.C_brkEditField.Value = Paraset(3);
                app.P_vEditField.Value = Paraset(4);
                app.C_vsetEditField.Value = Paraset(5);
                app.a_maxEditField.Value = Paraset(6);
                app.j_maxEditField.Value = Paraset(7);
                app.ovt_tolEditField.Value = Paraset(8);
                % update status
                app.StatusLamp.Color = [103  154  29] ./255; % green
                app.DSstatusLabel.Text = "Driving style input is valid.";
                app.GenerateCycleButton.Enable = "on";
            end
        end
        
        function results = updatePlotSel(app) % update plot selection
            switch app.PlotSelect % move selection box
                case 1
                    app.SelectionImage.Position = app.UIAxes.Position;
                case 2
                    app.SelectionImage.Position = app.UIAxes_2.Position;
                case 3
                    app.SelectionImage.Position = app.UIAxes_3.Position;
                case 4
                    app.SelectionImage.Position = app.UIAxes_4.Position;
                case 5
                    app.SelectionImage.Position = app.UIAxes_5.Position;
                case 6
                    app.SelectionImage.Position = app.UIAxes_6.Position;
            end
            if isfield(app.DC_Logs{app.PlotSelect},'Logs')
                app.ExportCycleButton.Enable = "on";
                app.ExportallDataButton.Enable = "on";
            else
                app.ExportCycleButton.Enable = "off";
                app.ExportallDataButton.Enable = "off";
            end
        end
        
        function results = pushMsg(app, msg)
            app.MessagesTextArea.Value = [app.MessagesTextArea.Value; msg];
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Adding necessary folders to path
            currentDir = cd;
            % current folder = 01_Main
            k = strfind(currentDir,'\01_Main');
            if ~isempty(k)
                k = max(k);
                basefolder = extractBefore(currentDir,k);
            else
            % find 03_Programm
                k = strfind(currentDir,'\03_Programm');
                if ~isempty(k)
                    k = max(k);
                    basefolder = extractBefore(currentDir,k+12);
                else
                    basefolder = currentDir;
                end
            end
            cd(basefolder)
            addpath(genpath(basefolder))
            app.CaliTab = load('Cali7tab.mat','Cali7tab').Cali7tab; % load calibrated parameters table
            % reset 10s in the calibration table
            for ii = 1:size(app.CaliTab,1)
                for jj = 9:11
                    if(app.CaliTab.(jj)(ii) >= 9.5)
                        app.CaliTab.(jj)(ii) = 10;
                    end
                end
            end
            % Opening the window maximized
            drawnow;
            app.MainScreen.WindowState = 'maximized';
            % lock parameters
            app.ovt_tolEditField.Editable = "off";
            app.j_maxEditField.Editable = "off";
            app.a_maxEditField.Editable = "off";
            app.C_vsetEditField.Editable = "off";
            app.P_vEditField.Editable = "off";
            app.P_aEditField.Editable = "off";
            app.C_brkEditField.Editable = "off";
            app.t_setEditField.Editable = "off";
            app.ExportCycleButton.Enable = "off";
            app.ExportallDataButton.Enable = "off";
            app.updateDS;
            app.MessagesTextArea.Value = "Tool loaded and standby.";
        end

        % Selection changed function: InputModeButtonGroup
        function InputModeButtonGroupSelectionChanged(app, event)
%             selectedButton = app.InputModeButtonGroup.SelectedObject;
            if strcmp(event.NewValue.Text,'Driving Style')
                % lock & unlock
                app.SwiftnessSpinner.Editable = "on";
                app.ComfortSpinner.Editable = "on";
                app.SafetySpinner.Editable = "on";
                app.SwiftnessSpinner.Enable = "on";
                app.ComfortSpinner.Enable = "on";
                app.SafetySpinner.Enable = "on";
                app.ovt_tolEditField.Editable = "off";
                app.j_maxEditField.Editable = "off";
                app.a_maxEditField.Editable = "off";
                app.C_vsetEditField.Editable = "off";
                app.P_vEditField.Editable = "off";
                app.P_aEditField.Editable = "off";
                app.C_brkEditField.Editable = "off";
                app.t_setEditField.Editable = "off";
                app.updateDS;
            else
                app.GenerateCycleButton.Enable = "on";
                % lock & unlock
                app.SwiftnessSpinner.Editable = "off";
                app.ComfortSpinner.Editable = "off";
                app.SafetySpinner.Editable = "off";
                app.SwiftnessSpinner.Enable = "off";
                app.ComfortSpinner.Enable = "off";
                app.SafetySpinner.Enable = "off";
                app.ovt_tolEditField.Editable = "on";
                app.j_maxEditField.Editable = "on";
                app.a_maxEditField.Editable = "on";
                app.C_vsetEditField.Editable = "on";
                app.P_vEditField.Editable = "on";
                app.P_aEditField.Editable = "on";
                app.C_brkEditField.Editable = "on";
                app.t_setEditField.Editable = "on";
            end
        end

        % Button pushed function: GenerateCycleButton
        function GenerateCycleButtonPushed(app, event)
            Pdlg = uiprogressdlg(app.MainScreen,'Title','Generating driving cycle',...
                'Message','Loading the parameters...');
%             pushMsg(app, "Cycle Generation requested.")
%             app.GenerateCycleButton.Enable = "off";
            % switch to next empty plot
            for ii = 0:5
                it = mod(app.PlotSelect - 1 + ii, 6) + 1;
                if isfield(app.DC_Logs{it},'Logs')
                    it = it + 1;
                else
                    app.PlotSelect = it;
                    break;
                end
            end
            updatePlotSel(app);
            % prepare Parameters
%             pushMsg(app, "Loading parameters ...")
            Paraarray = zeros(1,8);
            Paraarray(1) = app.t_setEditField.Value;
            Paraarray(2) = app.P_aEditField.Value;
            Paraarray(3) = app.C_brkEditField.Value;
            Paraarray(4) = app.P_vEditField.Value;
            Paraarray(5) = app.C_vsetEditField.Value;
            Paraarray(6) = app.a_maxEditField.Value;
            Paraarray(7) = app.j_maxEditField.Value;
            Paraarray(8) = app.ovt_tolEditField.Value;
            Paraset = Get_Paraset(Paraarray);
            Paraset.t_delta_traf = app.TrafficIntervalsEditField.Value;
            Scen.type = 'auto';
            Scen.ovt = 'auto';
            switch app.TrafficCycleDropDown.Value % choose traffic cycle
                case "ARTEMIS City"
                    Scen.cycle = 'EUROPE_ARTEMIS_URBAN';
                    Scen.type = 'urban';
                case "ARTEMIS Road"
                    Scen.cycle = 'EUROPE_ARTEMIS_ROAD';
                    Scen.type = 'road';
                case "ARTEMIS Motorway"
                    Scen.cycle = 'EUROPE_ARTEMIS_MOTORWAY';
                    Scen.type = 'highway';
                case "WLTC Class3"
                    Scen.cycle = 'WLTP_class_3';
                case "NEDC"
                    Scen.cycle = 'EUROPE_NEDC';
                case "FTP 72"
                    Scen.cycle = 'USA_FTP_72';
                case "FTP 75"
                    Scen.cycle = 'USA_FTP_75';
                case "FTP Highway"
                    Scen.cycle = 'USA_FTP_HIGHWAY';
                    Scen.type = 'highway';
                otherwise
                    error('GUI_AVDC: Invalid Traffic Cycle!')
            end
            % run simulation
            Pdlg.Value = .2; 
            Pdlg.Message = 'Running Simulation ...';
%             pushMsg(app, "Starting Simulation ...")
            app.DC_Logs{app.PlotSelect} = Sim_AVDC(Scen,Paraset);
            Pdlg.Value = .8; 
            Pdlg.Message = 'Ploting generated cycle ...';
%             pushMsg(app, "Simulation completed.")
            % plot
            switch app.PlotSelect
                case 1
                    ax = app.UIAxes;
                case 2
                    ax = app.UIAxes_2;
                case 3
                    ax = app.UIAxes_3;
                case 4
                    ax = app.UIAxes_4;
                case 5
                    ax = app.UIAxes_5;
                case 6
                    ax = app.UIAxes_6;
            end
            cla(ax)
            l1 = plot(ax,app.DC_Logs{app.PlotSelect}.Logs.v_ego.Time, ...
                app.DC_Logs{app.PlotSelect}.Logs.v_ego.Data .* 3.6);
            l1.Color = [0 82 147] ./255;
            l1.LineWidth = 1.25;
            xlabel(ax,'Time /s');
            ylabel(ax,'Speed /(km/h)');
            grid(ax,"on");
            hold(ax,"on");
            l2 = plot(ax,app.DC_Logs{app.PlotSelect}.Logs.v_front.Time, ...
                app.DC_Logs{app.PlotSelect}.Logs.v_front.Data .* 3.6,'--');
            l2.Color = [227 114 34] ./255;
            yticks(ax,"auto");
            ylim(ax,"auto");
            ylimits = ylim(ax);
            ylim(ax,[0,ylimits(2)]);
            hold(ax,"off");
            updatePlotSel(app);
            Pdlg.Value = 1; 
            Pdlg.Message = 'Cycle Generation finished.';
            % record settings
            Settings.comf = app.ComfortSpinner.Value;
            Settings.safe = app.SafetySpinner.Value;
            Settings.fast = app.SwiftnessSpinner.Value;
            Settings.t_set = app.t_setEditField.Value;
            Settings.K_brk = app.C_brkEditField.Value;
            Settings.P_a_acc = app.P_aEditField.Value;
            Settings.P_v_acc = app.P_vEditField.Value;
            Settings.a_max = app.a_maxEditField.Value;
            Settings.j_min = app.j_maxEditField.Value;
            Settings.set_speed = app.C_vsetEditField.Value;
            Settings.ovt_tol = app.ovt_tolEditField.Value;
            Settings.traf_cycle = app.TrafficCycleDropDown.Value;
            Settings.traf_dt = app.TrafficIntervalsEditField.Value;
            app.DC_Logs{app.PlotSelect}.Settings = Settings;
%             app.GenerateCycleButton.Enable = "on";
%             pushMsg(app, "Cycle Generation finished.")
            close(Pdlg);
        end

        % Button pushed function: ExportCycleButton
        function ExportCycleButtonPushed(app, event)
            dc.t = app.DC_Logs{app.PlotSelect}.Logs.v_ego.Time;
            dc.v = app.DC_Logs{app.PlotSelect}.Logs.v_ego.Data .* 3.6; % convert to [km/h]
            Settings = app.DC_Logs{app.PlotSelect}.Settings;
            filename = ['DC_',num2str(Settings.comf),'-',...
                num2str(Settings.safe),'-',num2str(Settings.fast),...
                '_',Settings.traf_cycle,'_',datestr(now,'yymmdd')];
            cd("01_Main\Generated_Cycles\")
            uisave('dc',filename)
            cd("..\..\")
        end

        % Button pushed function: ExportallDataButton
        function ExportallDataButtonPushed(app, event)
            Res = app.DC_Logs{app.PlotSelect};
            Settings = app.DC_Logs{app.PlotSelect}.Settings;
            filename = ['Data_',num2str(Settings.comf),'-',...
                num2str(Settings.safe),'-',num2str(Settings.fast),...
                '_',Settings.traf_cycle,'_',datestr(now,'yymmdd')];
            cd("01_Main\Generated_Cycles\")
            uisave('Res',filename)
            cd("..\..\")
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            app.delete;
        end

        % Value changed function: ComfortSpinner, SafetySpinner, 
        % SwiftnessSpinner
        function ComfortSpinnerValueChanged(app, event)
%             value = app.ComfortSpinner.Value;
            app.updateDS;
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            app.PlotSelect = 1;
            updatePlotSel(app);
        end

        % Button down function: UIAxes_2
        function UIAxes_2ButtonDown(app, event)
            app.PlotSelect = 2;
            updatePlotSel(app);
        end

        % Button down function: UIAxes_3
        function UIAxes_3ButtonDown(app, event)
            app.PlotSelect = 3;
            updatePlotSel(app);
        end

        % Button down function: UIAxes_4
        function UIAxes_4ButtonDown(app, event)
            app.PlotSelect = 4;
            updatePlotSel(app);
        end

        % Button down function: UIAxes_5
        function UIAxes_5ButtonDown(app, event)
            app.PlotSelect = 5;
            updatePlotSel(app);
        end

        % Button down function: UIAxes_6
        function UIAxes_6ButtonDown(app, event)
            app.PlotSelect = 6;
            updatePlotSel(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MainScreen = uifigure('Visible', 'off');
            app.MainScreen.Name = 'AVDC Tool';
            app.MainScreen.Icon = 'FTMLogo.PNG';
            app.AutonomousDrivingCycleGeneratorbasedonDrvingStyleLabel = uilabel(app.MainScreen);
            app.AutonomousDrivingCycleGeneratorbasedonDrvingStyleLabel.Text = '    Autonomous Driving Cycle Generator based on Drving Style';
            app.MainMenuPanel = uipanel(app.MainScreen);
            app.MainMenuPanel.Title = 'Main Menu';
            app.DrivingStylePanel = uipanel(app.MainMenuPanel);
            app.DrivingStylePanel.Title = 'Driving Style';
            app.SafetySpinnerLabel = uilabel(app.DrivingStylePanel);
            app.SafetySpinnerLabel.Text = 'Safety';
            app.SafetySpinner = uispinner(app.DrivingStylePanel);
            app.SafetySpinner.Limits = [5 10];
            app.SafetySpinner.RoundFractionalValues = 'on';
            app.SafetySpinner.ValueDisplayFormat = '%.0f';
            app.SafetySpinner.ValueChangedFcn = createCallbackFcn(app, @ComfortSpinnerValueChanged, true);
            app.SafetySpinner.Tooltip = {'Number of persons who are interested in using a mobility device.'};
            app.SafetySpinner.Value = 7;
            app.ComfortSpinnerLabel = uilabel(app.DrivingStylePanel);
            app.ComfortSpinnerLabel.Text = 'Comfort';
            app.ComfortSpinner = uispinner(app.DrivingStylePanel);
            app.ComfortSpinner.Limits = [5 10];
            app.ComfortSpinner.RoundFractionalValues = 'on';
            app.ComfortSpinner.ValueDisplayFormat = '%.0f';
            app.ComfortSpinner.ValueChangedFcn = createCallbackFcn(app, @ComfortSpinnerValueChanged, true);
            app.ComfortSpinner.Tooltip = {'Number of persons who are interested in using a mobility device.'};
            app.ComfortSpinner.Value = 7;
            app.SwiftnessSpinnerLabel = uilabel(app.DrivingStylePanel);
            app.SwiftnessSpinnerLabel.Text = 'Swiftness';
            app.SwiftnessSpinner = uispinner(app.DrivingStylePanel);
            app.SwiftnessSpinner.Limits = [5 10];
            app.SwiftnessSpinner.RoundFractionalValues = 'on';
            app.SwiftnessSpinner.ValueDisplayFormat = '%.0f';
            app.SwiftnessSpinner.ValueChangedFcn = createCallbackFcn(app, @ComfortSpinnerValueChanged, true);
            app.SwiftnessSpinner.Tooltip = {'Number of persons who are interested in using a mobility device.'};
            app.SwiftnessSpinner.Value = 7;
            app.DSstatusLabel = uilabel(app.DrivingStylePanel);
            app.DSstatusLabel.Text = 'DSstatus';
            app.StatusLamp = uilamp(app.DrivingStylePanel);
            app.ControllerParametersPanel = uipanel(app.MainMenuPanel);
            app.ControllerParametersPanel.Title = 'Controller Parameters';
            app.t_setEditField = uieditfield(app.ControllerParametersPanel, 'numeric');
            app.t_setEditField.Limits = [0.2 4];
            app.t_setEditField.Value = 0.5;
            app.t_setEditFieldLabel = uilabel(app.ControllerParametersPanel);
            app.t_setEditFieldLabel.Text = 't_set';
            app.C_brkEditFieldLabel = uilabel(app.ControllerParametersPanel);
            app.C_brkEditFieldLabel.Text = 'C_brk';
            app.C_brkEditField = uieditfield(app.ControllerParametersPanel, 'numeric');
            app.C_brkEditField.Limits = [0.5 2];
            app.C_brkEditField.Value = 0.5;
            app.P_aEditFieldLabel = uilabel(app.ControllerParametersPanel);
            app.P_aEditFieldLabel.Text = 'P_a';
            app.P_aEditField = uieditfield(app.ControllerParametersPanel, 'numeric');
            app.P_aEditField.Limits = [0.1 4];
            app.P_aEditField.Value = 0.1;
            app.P_vEditFieldLabel = uilabel(app.ControllerParametersPanel);
            app.P_vEditFieldLabel.Text = 'P_v';
            app.P_vEditField = uieditfield(app.ControllerParametersPanel, 'numeric');
            app.P_vEditField.Limits = [0.01 1];
            app.P_vEditField.Value = 0.01;
            app.C_vsetEditFieldLabel = uilabel(app.ControllerParametersPanel);
            app.C_vsetEditFieldLabel.Text = 'C_vset';
            app.C_vsetEditField = uieditfield(app.ControllerParametersPanel, 'numeric');
            app.C_vsetEditField.Limits = [0.5 2];
            app.C_vsetEditField.Value = 0.5;
            app.a_maxEditFieldLabel = uilabel(app.ControllerParametersPanel);
            app.a_maxEditFieldLabel.Text = 'a_max';
            app.a_maxEditField = uieditfield(app.ControllerParametersPanel, 'numeric');
            app.a_maxEditField.Limits = [0.5 8];
            app.a_maxEditField.Value = 0.5;
            app.j_maxEditFieldLabel = uilabel(app.ControllerParametersPanel);
            app.j_maxEditFieldLabel.Text = 'j_max';
            app.j_maxEditField = uieditfield(app.ControllerParametersPanel, 'numeric');
            app.j_maxEditField.Limits = [1 20];
            app.j_maxEditField.Value = 1;
            app.ovt_tolEditFieldLabel = uilabel(app.ControllerParametersPanel);
            app.ovt_tolEditFieldLabel.Text = 'ovt_tol';
            app.ovt_tolEditField = uieditfield(app.ControllerParametersPanel, 'numeric');
            app.ovt_tolEditField.Limits = [0 60];
            app.ActionsPanel = uipanel(app.MainMenuPanel);
            app.ActionsPanel.Title = 'Actions';
            app.GenerateCycleButton = uibutton(app.ActionsPanel, 'push');
            app.GenerateCycleButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateCycleButtonPushed, true);
            app.GenerateCycleButton.Tooltip = {''};
            app.GenerateCycleButton.Text = 'Generate Cycle';
            app.ExportCycleButton = uibutton(app.ActionsPanel, 'push');
            app.ExportCycleButton.ButtonPushedFcn = createCallbackFcn(app, @ExportCycleButtonPushed, true);
            app.ExportCycleButton.Tooltip = {''};
            app.ExportCycleButton.Text = 'Export Cycle';
            app.ExitButton = uibutton(app.ActionsPanel, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Tooltip = {''};
            app.ExitButton.Text = 'Exit';
            app.ExportallDataButton = uibutton(app.ActionsPanel, 'push');
            app.ExportallDataButton.ButtonPushedFcn = createCallbackFcn(app, @ExportallDataButtonPushed, true);
            app.ExportallDataButton.Tooltip = {''};
            app.ExportallDataButton.Text = 'Export all Data';
            app.InputModeButtonGroup = uibuttongroup(app.MainMenuPanel);
            app.InputModeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @InputModeButtonGroupSelectionChanged, true);
            app.InputModeButtonGroup.Title = '    Input Mode';
            app.DrivingStyleButton = uiradiobutton(app.InputModeButtonGroup);
            app.DrivingStyleButton.Text = 'Driving Style';
            app.DrivingStyleButton.Value = true;
            app.ControllerParameterButton = uiradiobutton(app.InputModeButtonGroup);
            app.ControllerParameterButton.Text = 'Controller Parameter';
            app.TrafficSettingsPanel = uipanel(app.MainMenuPanel);
            app.TrafficSettingsPanel.Title = 'Traffic Settings';
            app.TrafficCycleDropDownLabel = uilabel(app.TrafficSettingsPanel);
            app.TrafficCycleDropDownLabel.Text = 'Traffic Cycle';
            app.TrafficCycleDropDown = uidropdown(app.TrafficSettingsPanel);
            app.TrafficCycleDropDown.Items = {'ARTEMIS City', 'ARTEMIS Road', 'ARTEMIS Motorway', 'WLTC Class3', 'NEDC', 'FTP 72', 'FTP 75', 'FTP Highway'};
            app.TrafficCycleDropDown.Tooltip = {'Choose from different predefined scenarios.'};
            app.TrafficCycleDropDown.Value = 'ARTEMIS City';
            app.TrafficIntervalsEditFieldLabel = uilabel(app.TrafficSettingsPanel);
            app.TrafficIntervalsEditFieldLabel.Text = 'Traffic Interval (s)';
            app.TrafficIntervalsEditField = uieditfield(app.TrafficSettingsPanel, 'numeric');
            app.TrafficIntervalsEditField.Limits = [5 120];
            app.TrafficIntervalsEditField.Value = 10;
            app.MessagesTextAreaLabel = uilabel(app.MainMenuPanel);
            app.MessagesTextAreaLabel.Enable = 'off';
            app.MessagesTextAreaLabel.Visible = 'off';
            app.MessagesTextAreaLabel.Text = 'Messages';
            app.MessagesTextArea = uitextarea(app.MainMenuPanel);
            app.MessagesTextArea.Editable = 'off';
            app.MessagesTextArea.Enable = 'off';
            app.MessagesTextArea.Visible = 'off';
            app.GeneratedDrivingCyclePanel = uipanel(app.MainScreen);
            app.GeneratedDrivingCyclePanel.Title = 'Generated Driving Cycle';
            app.SelectionImage = uiimage(app.GeneratedDrivingCyclePanel);
            app.SelectionImage.ImageSource = 'PlotSelectionBox.png';
            app.UIAxes = uiaxes(app.GeneratedDrivingCyclePanel);
            title(app.UIAxes, 'Cycle 1')
            xlabel(app.UIAxes, 'Time (s)')
            ylabel(app.UIAxes, 'Speed (km/h)')
            app.UIAxes.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.UIAxes_2 = uiaxes(app.GeneratedDrivingCyclePanel);
            title(app.UIAxes_2, 'Cycle 2')
            xlabel(app.UIAxes_2, 'Time (s)')
            ylabel(app.UIAxes_2, 'Speed (km/h)')
            app.UIAxes_2.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.UIAxes_2.ButtonDownFcn = createCallbackFcn(app, @UIAxes_2ButtonDown, true);
            app.UIAxes_3 = uiaxes(app.GeneratedDrivingCyclePanel);
            title(app.UIAxes_3, 'Cycle 3')
            xlabel(app.UIAxes_3, 'Time (s)')
            ylabel(app.UIAxes_3, 'Speed (km/h)')
            app.UIAxes_3.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.UIAxes_3.ButtonDownFcn = createCallbackFcn(app, @UIAxes_3ButtonDown, true);
            app.UIAxes_4 = uiaxes(app.GeneratedDrivingCyclePanel);
            title(app.UIAxes_4, 'Cycle 4')
            xlabel(app.UIAxes_4, 'Time (s)')
            ylabel(app.UIAxes_4, 'Speed (km/h)')
            app.UIAxes_4.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.UIAxes_4.ButtonDownFcn = createCallbackFcn(app, @UIAxes_4ButtonDown, true);
            app.UIAxes_5 = uiaxes(app.GeneratedDrivingCyclePanel);
            title(app.UIAxes_5, 'Cycle 5')
            xlabel(app.UIAxes_5, 'Time (s)')
            ylabel(app.UIAxes_5, 'Speed (km/h)')
            app.UIAxes_5.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.UIAxes_5.ButtonDownFcn = createCallbackFcn(app, @UIAxes_5ButtonDown, true);
            app.UIAxes_6 = uiaxes(app.GeneratedDrivingCyclePanel);
            title(app.UIAxes_6, 'Cycle 6')
            xlabel(app.UIAxes_6, 'Time (s)')
            ylabel(app.UIAxes_6, 'Speed (km/h)')
            app.UIAxes_6.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.UIAxes_6.ButtonDownFcn = createCallbackFcn(app, @UIAxes_6ButtonDown, true);
            app.TUMlogo = uiimage(app.MainScreen);
            app.TUMlogo.ImageSource = '2015_Logo_TUM_neg_RGB.svg';
            % Show the figure after all components are created
            app.MainScreen.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_AVDC

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MainScreen)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MainScreen)
        end
    end
end